classdef Bai9B < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        L                     matlab.ui.control.NumericEditField
        LEditFieldLabel       matlab.ui.control.Label
        W                     matlab.ui.control.NumericEditField
        WEditFieldLabel       matlab.ui.control.Label
        Lambda                matlab.ui.control.NumericEditField
        LambdaEditFieldLabel  matlab.ui.control.Label
        VT                    matlab.ui.control.NumericEditField
        VTEditFieldLabel      matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        Draw                  matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: VT
        function VTValueChanged(app, event)
            
            
        end

        % Value changed function: Lambda
        function LambdaValueChanged(app, event)
            
            
        end

        % Value changed function: W
        function WValueChanged(app, event)
            
            
        end

        % Value changed function: L
        function LValueChanged(app, event)
            
            
        end

        % Button pushed function: Draw
        function DrawButtonPushed(app, event)
            muy_nC_ox = 200;
            Vds = 0:0.1:5;
            Vgs_values = [0.5 2 3.5 4.5];
            Vth = app.VT.Value;
            lambda = app.Lambda.Value;
            W = app.W.Value;
            L = app.L.Value;            
            % Ids = zeros(size(Vds));
            hold(app.UIAxes, 'on');

            for j = 1:length(Vgs_values)
                vgs = Vgs_values(j);
                m = length(Vds);
                current = zeros(1, m);
            
                for i = 1:m
                    if vgs < Vth
                        current(1, i) = 0;
                    elseif Vds(i) >= (vgs - Vth)
                        current(1, i) = 0.5 * (W/L) * ((vgs - Vth)^2) * (1 + lambda * Vds(i)); % Added DIBL
                    elseif Vds(i) < (vgs - Vth)
                        current(1, i) = (W/L) * ((vgs - Vth) * Vds(i) - 0.5 * (Vds(i)^2)) * (1 + lambda * Vds(i)); % Simplified equation by approximation
                    end
                end            
                plot(app.UIAxes, Vds, current(1, :), 'DisplayName', ['Vgs = ', num2str(vgs), 'V']);                
            end
            hold(app.UIAxes, 'off');
            legend(app.UIAxes);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị đặc tính MOSFET kênh N')
            xlabel(app.UIAxes, 'Vds, V')
            ylabel(app.UIAxes, 'Ids, mA')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Draw = uibutton(app.UIFigure, 'push');
            app.Draw.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.Draw.Text = 'Vẽ đồ thị';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Nhập giá trị các tham số';
            app.VTEditFieldLabel = uilabel(app.UIFigure);
            app.VTEditFieldLabel.Text = 'VT';
            app.VT = uieditfield(app.UIFigure, 'numeric');
            app.VT.ValueChangedFcn = createCallbackFcn(app, @VTValueChanged, true);
            app.LambdaEditFieldLabel = uilabel(app.UIFigure);
            app.LambdaEditFieldLabel.Text = 'Lambda';
            app.Lambda = uieditfield(app.UIFigure, 'numeric');
            app.Lambda.ValueChangedFcn = createCallbackFcn(app, @LambdaValueChanged, true);
            app.WEditFieldLabel = uilabel(app.UIFigure);
            app.WEditFieldLabel.Text = 'W';
            app.W = uieditfield(app.UIFigure, 'numeric');
            app.W.ValueChangedFcn = createCallbackFcn(app, @WValueChanged, true);
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.L = uieditfield(app.UIFigure, 'numeric');
            app.L.ValueChangedFcn = createCallbackFcn(app, @LValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bai9B

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end