classdef Wave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        time               matlab.ui.control.Knob
        ThigiantKnobLabel  matlab.ui.control.Label
        scale              matlab.ui.control.Switch
        HzkHzSwitchLabel   matlab.ui.control.Label
        freq               matlab.ui.control.Knob
        TnsKnobLabel       matlab.ui.control.Label
        Amp                matlab.ui.control.Knob
        BinVKnobLabel      matlab.ui.control.Label
        Waveform           matlab.ui.control.DiscreteKnob
        DngsngKnobLabel    matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    
    methods (Access = private)        
        function UpdateButtonPushed(app, ~)
            wave = app.Waveform.Value;
            frequency = app.freq.Value;
            amplitude = app.Amp.Value;
            ts = app.time.Value;
            scalef = app.scale.Value;            
            if scalef == 1
                frequency = frequency*1;
            elseif scalef == 2
                frequency = frequency*1000;
            end
            t = 0:0.0001:ts;       
            if wave == 1 %Sine
                waveform = amplitude * sin(2 * pi * frequency * t);
            elseif wave == 2 %Triangle
                waveform = sawtooth(2*pi*frequency*t, 0.5);
            elseif wave == 3 %Square
                waveform = amplitude * square(2*pi*frequency*t, 0.5);
            elseif wave == 4 %Ramp
                waveform = amplitude * t;
            end
            plot(app.UIAxes, t, waveform);
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Waveform
        function WaveformValueChanged(app, event)
            UpdateButtonPushed(app);            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Value changed function: Amp
        function AmpValueChanged(app, event)
            UpdateButtonPushed(app); 
        end

        % Value changed function: freq
        function freqValueChanged(app, event)
            UpdateButtonPushed(app); 
        end

        % Value changed function: time
        function timeValueChanged(app, event)
            UpdateButtonPushed(app); 
        end

        % Value changed function: scale
        function scaleValueChanged(app, event)
            UpdateButtonPushed(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Dạng sóng')
            xlabel(app.UIAxes, 'Thời gian (s)')
            ylabel(app.UIAxes, 'Biên độ (V)')
            zlabel(app.UIAxes, 'Z')
            app.DngsngKnobLabel = uilabel(app.UIFigure);
            app.DngsngKnobLabel.Text = 'Dạng sóng';
            app.Waveform = uiknob(app.UIFigure, 'discrete');
            app.Waveform.Items = {'Sine', 'Triangle', 'Square', 'Ramp'};
            app.Waveform.ItemsData = [1 2 3 4];
            app.Waveform.ValueChangedFcn = createCallbackFcn(app, @WaveformValueChanged, true);
            app.Waveform.Value = 1;
            app.BinVKnobLabel = uilabel(app.UIFigure);
            app.BinVKnobLabel.Text = 'Biên độ (V)';
            app.Amp = uiknob(app.UIFigure, 'continuous');
            app.Amp.Limits = [0 5];
            app.Amp.ValueChangedFcn = createCallbackFcn(app, @AmpValueChanged, true);
            app.TnsKnobLabel = uilabel(app.UIFigure);
            app.TnsKnobLabel.Text = 'Tần số';
            app.freq = uiknob(app.UIFigure, 'continuous');
            app.freq.ValueChangedFcn = createCallbackFcn(app, @freqValueChanged, true);
            app.HzkHzSwitchLabel = uilabel(app.UIFigure);
            app.HzkHzSwitchLabel.Text = 'Hz/kHz';
            app.scale = uiswitch(app.UIFigure, 'slider');
            app.scale.Items = {'Hz', 'kHz'};
            app.scale.ItemsData = [1 2];
            app.scale.ValueChangedFcn = createCallbackFcn(app, @scaleValueChanged, true);
            app.scale.Value = 1;
            app.ThigiantKnobLabel = uilabel(app.UIFigure);
            app.ThigiantKnobLabel.Text = 'Thời gian (t)';
            app.time = uiknob(app.UIFigure, 'continuous');
            app.time.Limits = [0 10];
            app.time.ValueChangedFcn = createCallbackFcn(app, @timeValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Wave

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end