classdef DoAn_Hoiquy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        predict_value               matlab.ui.control.EditField
        GitrcndonEditFieldLabel_2   matlab.ui.control.Label
        predict_result              matlab.ui.control.TextArea
        KtqudonTextAreaLabel        matlab.ui.control.Label
        regress_func                matlab.ui.control.TextArea
        PhngtrnhhiquyTextAreaLabel  matlab.ui.control.Label
        y_value                     matlab.ui.control.EditField
        GitryEditFieldLabel         matlab.ui.control.Label
        x_value                     matlab.ui.control.EditField
        GitrxEditFieldLabel         matlab.ui.control.Label
        Start                       matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        regress_method              matlab.ui.control.ListBox
        PhngphpListBoxLabel         matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [x, y] = InputData(app)
            x_input = app.x_value.Value;
            x_array = strsplit(x_input, ' ');
            x = str2double(x_array);
            y_input = app.y_value.Value;
            y_array = strsplit(y_input, ' ');
            y = str2double(y_array);
        end
        
        function Linear_Regession(app)
            [x, y] = InputData(app);
            x_predict_inp = app.predict_value.Value;
            x_predict = str2double(x_predict_inp);
            n = length(x)
            sumxy = 0;
            sumx = 0;
            sumy = 0;
            sumx2 = 0;
    
            for i = 1:1:n
                sumxy = sumxy + x(i)*y(i);
                sumx = sumx + x(i);
                sumy = sumy + y(i);
                sumx2 = sumx2 + x(i)^2;
            end
            a1 = (n * sumxy - sumx * sumy)/(n * sumx2 - (sumx)^2)
            a0 = mean(y) - a1 * mean(x)
            x_fit = linspace(min(x), max(x), 100); % Generate x values for the line
            y_fit = a0 + a1 * x_fit; % Compute corresponding y values
            plot(app.UIAxes, x, y , 'ro', x_fit, y_fit, 'b-', 'LineWidth', 2);
            legend(app.UIAxes, 'Dữ liệu thực', 'Hàm hồi quy');
            y_predict = a1 * x_predict + a0;
            
            y_predict1 = num2str(y_predict);
            app.predict_result.Value = y_predict1;

            print = ['y = ' num2str(a1) 'x + ' num2str(a0)];
            app.regress_func.Value = print;
        end
        
        function Non_Linear_Exp(app)
            [x, y] = InputData(app);
            x_predict_inp = app.predict_value.Value;
            x_predict = str2double(x_predict_inp);
            Y = log(y);
            X = [ones(length(x), 1), x']
            coefficients = X\Y'
            A0 = coefficients(1);
            A1 = coefficients(2);
            y_fit = exp(A0 + A1 * x);
            plot(app.UIAxes, x, y, 'ro', x, y_fit, 'b-', 'LineWidth', 2);
            legend(app.UIAxes, 'Dữ liệu thực', 'Hàm hồi quy');
            y_predict = exp(A0 + A1 * x_predict);
            y_predict1 = num2str(y_predict);
            app.predict_result.Value = y_predict1;
            
            a = num2str(exp(A0));
            b = num2str(A1);
            print = ['y = ' a 'e^' b 'x'];
            app.regress_func.Value = print;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: regress_method
        function regress_methodValueChanged(app, event)
            value = app.regress_method.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: Start
        function StartButtonPushed(app, event)
            if app.regress_method.Value == 1
                Linear_Regession(app);
            elseif app.regress_method.Value == 2
                Non_Linear_Exp(app);
            end
        end

        % Value changed function: regress_func
        function regress_funcValueChanged(app, event)
            value = app.regress_func.Value;
            
        end

        % Value changed function: predict_result
        function predict_resultValueChanged(app, event)
            value = app.predict_result.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị hàm hồi quy và dữ liệu thực')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PhngphpListBoxLabel = uilabel(app.UIFigure);
            app.PhngphpListBoxLabel.Text = 'Phương pháp';
            app.regress_method = uilistbox(app.UIFigure);
            app.regress_method.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.regress_method.ItemsData = [1 2 3];
            app.regress_method.ValueChangedFcn = createCallbackFcn(app, @regress_methodValueChanged, true);
            app.regress_method.Value = 1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'HỒI QUY';
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Text = 'Thực hiện';
            app.GitrxEditFieldLabel = uilabel(app.UIFigure);
            app.GitrxEditFieldLabel.Text = 'Giá trị x';
            app.x_value = uieditfield(app.UIFigure, 'text');
            app.GitryEditFieldLabel = uilabel(app.UIFigure);
            app.GitryEditFieldLabel.Text = 'Giá trị y';
            app.y_value = uieditfield(app.UIFigure, 'text');
            app.PhngtrnhhiquyTextAreaLabel = uilabel(app.UIFigure);
            app.PhngtrnhhiquyTextAreaLabel.Text = 'Phương trình hồi quy';
            app.regress_func = uitextarea(app.UIFigure);
            app.regress_func.ValueChangedFcn = createCallbackFcn(app, @regress_funcValueChanged, true);
            app.KtqudonTextAreaLabel = uilabel(app.UIFigure);
            app.KtqudonTextAreaLabel.Text = 'Kết quả dự đoán';
            app.predict_result = uitextarea(app.UIFigure);
            app.predict_result.ValueChangedFcn = createCallbackFcn(app, @predict_resultValueChanged, true);
            app.GitrcndonEditFieldLabel_2 = uilabel(app.UIFigure);
            app.GitrcndonEditFieldLabel_2.Text = 'Giá trị cần dự đoán';
            app.predict_value = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoAn_Hoiquy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end